classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        restart    matlab.ui.control.Button
        play       matlab.ui.control.Button
        videoAxes  matlab.ui.control.UIAxes
        plotAxes   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        videoReaders = {};        % Cell array of VideoReader objects
        video = {};
        currentFrame = [];        % Store the current video frame
        currentVideoIdx = 1;      % Track which video is playing
        running = false;          % Playback control state
        pixelTimeSeries = [];     % Store time-series data for a clicked pixel
        pixelToNumberMap; 
        C = [];               % Containing calcium traces
        A = [];  
        halflength_plot = [];
        imageHandle ={};
        boundaries = [nan nan ; nan nan];
        contourdrawn;
        fps = 10.0;
        frameCount;
        folderPath;
        
    end
    
    methods (Access = private)
        
        function loadVideos(app)
            %[files, path] = uigetfile({'*.mp4;*.avi;*.mov'}, 'Select Video Files', 'MultiSelect', 'on');
            %app.folderPath = uigetdir('', 'Select a Folder Containing Videos');
            [files, Path] = uigetfile({'*.mp4;*.avi;*.mov'}, 'Select a File in the Folder');
            app.folderPath = Path;
            
            %videoExtensions = {'*.mp4', '*.avi', '*.mov'}; % Add more extensions if needed
            if ischar(files)
                files = {files}; 
            end  

                        % Load videos into VideoReader objects
            % for i = 1:numel(videoFiles)
            %     videoPath = fullfile(app.folderPath, videoFiles(i).name);
            %     app.videoReaders{end+1} = VideoReader(videoPath);
            % end
            for i = 1:numel(files)
                videoPath = fullfile(app.folderPath, files{i});
                app.videoReaders{end+1} = VideoReader(videoPath);
            end
            %%%%this used to be in the create function 
            app.C = ncread(fullfile(app.folderPath,"C.nc"), 'C');
            A = ncread(fullfile(app.folderPath,"A.nc"), 'A');
            app.A = pagetranspose(A);
            [proj, ix] = max(app.A,[],3);
            app.pixelToNumberMap = ix; 
            app.pixelToNumberMap(sum(app.A,3) == 0) = 0;
            app.halflength_plot = floor(app.plotAxes.XLim(2)/2)
            app.pixelTimeSeries =  [nan(1, app.halflength_plot), app.C(:, 14)' ,nan(1, app.halflength_plot)];     % Store time-series data
            

        end
        
        function playVideos(app)
            app.running = true;
            startTime = tic;
            frameTime = 1 / app.fps;  % Target frame duration
            app.frameCount = 0;  % Frame counter
            while app.running                                                       
                for vidIdx = app.currentVideoIdx:numel(app.videoReaders)
                    app.currentVideoIdx = vidIdx;  % Track current video
                    app.video = app.videoReaders{1,vidIdx};
                    app.imageHandle = image(app.videoAxes, mat2gray(readFrame(app.video)), 'ButtonDownFcn', @(src, event) app.videoClick(src, event));
                    
                    while hasFrame(app.video) && app.running
                        
                        app.frameCount = app.frameCount + 1;
                      % Read and display the frame
                        if mod(app.frameCount,2) == 0
                            frameStart = tic;
                            app.currentFrame = mat2gray(readFrame(app.video));
                            app.imageHandle.CData =  app.currentFrame;
                            
                            % app.imageHandle.PickableParts = 'all';  
                            % app.imageHandle.HitTest = 'on';  
                            % app.imageHandle.ButtonDownFcn = @(src, event) app.videoClick(src, event);
                            % 
                            app.updateTimeSeries();
                            drawnow limitrate; % Display on videoAxes


                            elapsedTime = toc(startTime);  % Get elapsed time
                            actualFPS = app.frameCount / elapsedTime;  % Compute FP
                            fprintf('Frame %d | Actual FPS: %.2f\n', app.frameCount, actualFPS);
                            % Pause only if needed
                            timeLeft = frameTime - (toc - elapsedTime);  
    
    
                            % Pause to match frame rate
                            if ~app.running
                                break;  % Exit the loop immediately
                            end
                            elapsedFrameTime = toc(frameStart);
                            timeLeft = frameTime - elapsedFrameTime;
                            if timeLeft > 0
                                pause(timeLeft * 0.8); 
                            end
                            % Update the time-series plot
                            %app.updateTimeSeries();
                        end
                    end
                end
                app.currentVideoIdx = 1;  % Loop back to the first video
            end
        end
        
        function updateTimeSeries(app)
            % This method updates the time-series plot in real time
            if ~isempty(app.pixelTimeSeries)
                % Find current frame index (assuming constant frame rate)
                timeIdx = app.frameCount %floor(app.video.CurrentTime * 2* app.fps);
                if timeIdx > 0 && timeIdx <= numel(app.pixelTimeSeries)
                    %currentValue = app.pixelTimeSeries(timeIdx);
                    % Update the time-series plot
                    plot(app.plotAxes, 1:2*app.halflength_plot, app.pixelTimeSeries(timeIdx:timeIdx+2*app.halflength_plot-1), 'b');
                    hold(app.plotAxes, 'on');
                    xline(app.plotAxes, app.halflength_plot, 'r');  % Add vertical line for frame alignment
                    hold(app.plotAxes, 'off');
                end
            end  
        end
        
        function videoClick(app, src, event)
            % Get the coordinates of the click in the video
            clickPoint = event.IntersectionPoint;  % [x, y, z]
            x = round(clickPoint(1));  % Column index
            y = round(clickPoint(2));  % Row index

            % Ensure the click is within video bounds
            if x > 0 && x <= size(app.currentFrame, 2) && y > 0 && y <= size(app.currentFrame, 1)
                % Process the clicked pixel
                app.processClickedPixel(x, y);
                drawnow;
            end
            
        end
        
        function processClickedPixel(app, x, y)
            % Ensure x and y are within valid bounds
            if x < 1 || y < 1 || x > size(app.pixelToNumberMap, 1) || y > size(app.pixelToNumberMap, 2)
                return;
            end
        
            % Retrieve the corresponding number from the map
            cellValue = app.pixelToNumberMap(round(y), round(x));
        
            % If the value is nonzero, return it (or process it further)
            if cellValue ~= 1
                app.pixelTimeSeries = [nan(1, app.halflength_plot), app.C(:, cellValue)' ,nan(1, app.halflength_plot)]; 
                app.plotAxes.YLim = [0 ceil(max(app.C,[],"all"))];
                disp(['Unit ', num2str(cellValue)]);
                title(app.plotAxes, ['Unit ', num2str(cellValue)])

                mask = round(app.A(:,:,cellValue))';%(app.pixelToNumberMap == cellValue);
                se = strel('disk', 1);  % Structuring element (1-pixel expansion)
                expandedMask = imdilate(mask, se);
                app.boundaries = bwboundaries(expandedMask);

            else
                app.pixelTimeSeries = nan *app.pixelTimeSeries ;
                app.boundaries = []; %nan *app.boundaries;
            end
            % Plot the contour over the video frame
            hold(app.videoAxes, 'off');
            %app.currentFrame = mat2gray(readFrame(app.video));
            app.imageHandle.CData =  app.currentFrame;
            hold(app.videoAxes, 'on'); % Keep the video frame
            for k = 1:length(app.boundaries)
                boundary = app.boundaries{k};
                set(app.contourdrawn, 'XData', [], 'YData', []);
                app.contourdrawn = plot(app.videoAxes, boundary(:,1), boundary(:,2), 'r', 'LineWidth', 1); % Draw in red
                drawnow limitrate;
            end
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Initialize playback state variables
            app.videoReaders = {};        % Store VideoReader objects
            app.running = false;          % Whether playback is running
            app.currentVideoIdx = 1;      % Index of the current video 
            % app.C = ncread(fullfile(app.folderPath,"C.nc"), 'C');
            % A = ncread(fullfile(app.folderPath,"A.nc"), 'A');
            % app.A = pagetranspose(A);
            % [proj, ix] = max(app.A,[],3);
            % app.pixelToNumberMap = ix; 
            % app.pixelToNumberMap(sum(app.A,3) == 0) = 0;
            % app.halflength_plot = floor(app.plotAxes.XLim(2)/2)
            % app.pixelTimeSeries =  [nan(1, app.halflength_plot), app.C(:, 14)' ,nan(1, app.halflength_plot)];     % Store time-series data
            % 
            uialert(app.UIFigure, 'Welcome to the Video & Time-Series App!', 'Welcome');

            % Pre-load videos (optional)
            app.loadVideos();  % Uncomment if you want to prompt the user for videos at startup
            if ~isempty(app.videoReaders)
                firstVideo = app.videoReaders{1,1};
                firstFrame = readFrame(firstVideo);
                imshow(firstFrame, 'Parent', app.videoAxes);  % Display on videoAxes
            end

        end

        % Button pushed function: play
        function playButtonPushed(app, event)
            app.running = ~app.running;
            if app.running
                app.playVideos()
            end
        end

        % Button pushed function: restart
        function restartButtonPushed(app, event)
            % Stop playback
            app.running = false;

            % Reset video and plot
            app.video.CurrentTime = 0;  % Restart video
            cla(app.plotAxes);  % Clear time series plot
            cla(app.videoAxes); 
            hold(app.plotAxes, 'on');  % Prepare for new plot

            % Restart playback
            app.running = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'jet');
            app.UIFigure.Name = 'MATLAB App';
            app.plotAxes = uiaxes(app.UIFigure);
            title(app.plotAxes, 'Coming soon : indiv neuron calcium trace')
            ylabel(app.plotAxes, 'Activity')
            zlabel(app.plotAxes, 'Z')
            app.plotAxes.XLim = [1 1000];
            app.plotAxes.YLim = [-1 1];
            app.plotAxes.XTick = [100 250 400 500 520 600 750 900];
            app.plotAxes.XTickLabel = {'-400'; '-250'; '-100'; '0'; '20'; '100'; '250'; '400'};
            app.videoAxes = uiaxes(app.UIFigure);
            app.videoAxes.XTick = [0 600];
            app.videoAxes.XTickLabel = {'0'; '600'};
            app.videoAxes.YTick = [0 600];
            app.videoAxes.YTickLabel = {'0'; '600'};
            app.play = uibutton(app.UIFigure, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.play.Icon = fullfile(pathToMLAPP, 'playpause.png');
            app.play.Text = '';
            app.restart = uibutton(app.UIFigure, 'push');
            app.restart.ButtonPushedFcn = createCallbackFcn(app, @restartButtonPushed, true);
            app.restart.Icon = fullfile(pathToMLAPP, 'retry.png');
            app.restart.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end